classdef rwwp_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GenerateModelButton          matlab.ui.control.Button
        AmplitudeSliderLabel         matlab.ui.control.Label
        AmplitudeSlider              matlab.ui.control.Slider
        BuildingMaterialLabel        matlab.ui.control.Label
        BuildingMaterialDropDown     matlab.ui.control.DropDown
        WavePropagationModelLabel    matlab.ui.control.Label
        TypeofModelDropDownLabel     matlab.ui.control.Label
        TypeofModelDropDown          matlab.ui.control.DropDown
        RecieverSitelatandlongLabel  matlab.ui.control.Label
        RecieverSitelatandlongEditField  matlab.ui.control.EditField
        CityDropDownLabel            matlab.ui.control.Label
        CityDropDown                 matlab.ui.control.DropDown
        TerrainMaterialLabel         matlab.ui.control.Label
        TerrainMaterialDropDown      matlab.ui.control.DropDown
        RightPanel                   matlab.ui.container.Panel
        DefaultStrengthText          matlab.ui.control.Label
        TransmissionpowerLabel       matlab.ui.control.Label
        DirectDistancemLabel         matlab.ui.control.Label
        DefaultDistanceText          matlab.ui.control.Label
        AveragePathLossLabel         matlab.ui.control.Label
        AvgPathLossText              matlab.ui.control.Label
        NumberofreflectionsLabel     matlab.ui.control.Label
        NumReflectText               matlab.ui.control.Label
        NumRaysLabel                 matlab.ui.control.Label
        NumRaysText                  matlab.ui.control.Label
        TransmissionDetailsLabel     matlab.ui.control.Label
        DefaultStrengthText_2        matlab.ui.control.Label
        DefaultDistanceText_2        matlab.ui.control.Label
        AvgPathLossText_2            matlab.ui.control.Label
        NumReflectText_2             matlab.ui.control.Label
        NumRaysText_2                matlab.ui.control.Label
        Model1Label                  matlab.ui.control.Label
        Model2Label                  matlab.ui.control.Label
        DifferenceinreceivedpowerbetweenmodelsLabel  matlab.ui.control.Label
        PowerDifferenceText          matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
        %Default values for model
        city = "Toronto";
        transLat = 43.64163;
        transLong =-79.38204;
        antenna_height = 10;
        amplitude = 10;
        bMaterial = "perfect-reflector";
        tMaterial = "concrete";
        recLat = 43.64233;
        recLong = -79.38683;
        signalStrengthDefault = 0;
        transmitter;
        reciever;
        rtpm;
        ssd = 0;
        ssn = 0;
        defDistance;
        pl;
        LLArray;
        strength1 = 0;
        strength2 = 0;
        modelType;
        rays;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateModelButton
        function GenerateModelButtonPushed(app, event)
            %Creating site viewer window
           viewer = siteviewer("Buildings", strcat(app.city, ".osm"),"Basemap","topographic");
           
           %conditional block based on city name
           if app.city == "London"
               app.transLat = 51.50791;
               app.transLong = -0.12931;
               if isempty(app.RecieverSitelatandlongEditField.Value)
                   app.recLat = 51.5079;
                   app.recLong = -0.12725;
               else
                   app.recLat = str2double(app.LLArray{1,1});
                   app.recLong = str2double(app.LLArray{1,2});
               end
           elseif app.city == "Toronto"
               app.transLat = 43.64163;
               app.transLong = -79.38204;
               if isempty(app.RecieverSitelatandlongEditField.Value)
                   app.recLat = 43.64233;
                   app.recLong = -79.38683;
               else
                   app.recLat = str2double(app.LLArray{1,1});
                   app.recLong = str2double(app.LLArray{1,2});
               
               end
           end
           
           
           %Transmitter site
            app.transmitter = txsite("Name","Small cell transmitter", ...
                "Latitude", app.transLat, ... , 
                "Longitude", app.transLong, ...
                "AntennaHeight", app.antenna_height, ...
                "TransmitterPower", app.amplitude, ...
                "TransmitterFrequency",28e9);
                show(app.transmitter)
                  
                %Reciever site
                app.reciever = rxsite( ...
                    "Name","Small cell receiver", ...
                    "Latitude", app.recLat, ...
                    "Longitude", app.recLong, ...
                    "AntennaHeight", 2);
                
                %Ray tracing model
                app.rtpm = propagationModel( ...
                    "raytracing-image-method", ...
                    "MaxNumReflections",2, ...
                    "BuildingsMaterial",  app.bMaterial, ...
                    "TerrainMaterial", app.tMaterial);
             
                los(app.transmitter, app.reciever)
                %app.rtpm.MaxNumReflections = 2;   
                
                raytrace(app.transmitter, app.reciever, app.rtpm, "Type", "pathloss");
                app.rays = raytrace(app.transmitter, app.reciever, app.rtpm, "Type", "pathloss");
                 
                if strcmp(app.modelType, "Path Loss")
                    raytrace(app.transmitter, app.reciever, app.rtpm, "Type", "pathloss");
                    app.rays = raytrace(app.transmitter, app.reciever, app.rtpm, "Type", "pathloss");
                elseif strcmp(app.modelType, "Power Loss")
                    raytrace(app.transmitter, app.reciever, app.rtpm);
                    app.rays = raytrace(app.transmitter, app.reciever, app.rtpm);
                end
            
                 
                %if there is already info filled into the first section, fill new section with model details 
                if app.ssd ~= 0
                app.pl = app.rays{1}.PathLoss;
                fullpl = 0;
                indpl = 0;
                counter = 0;
                numReflections = 0;
                %accesses the ray property to get an average pathloss
                for i = 1:length(app.rays{1})
                        indpl = app.rays{1}(i).PathLoss;
                        fullpl = fullpl + indpl;
                        numReflections = numReflections + app.rays{1}(i).NumReflections;
                        counter = counter + 1;
                end
                app.NumRaysText_2.Text = num2str(counter);
                app.AvgPathLossText_2.Text = strcat(num2str(fullpl / counter), "dB");
                app.NumReflectText_2.Text = num2str(numReflections);
                app.signalStrengthDefault = sigstrength(app.reciever, app.transmitter, app.rtpm);
                app.defDistance = distance(app.transmitter, app.reciever);
                app.DefaultDistanceText_2.Text = strcat(num2str(app.defDistance, 6), "m");
                app.ssd = num2str(app.signalStrengthDefault, 5);
                app.DefaultStrengthText_2.Text =  strcat(app.ssd, "dBm");
                app.strength1 = app.ssd;


                %if the first section is still empty, fill with model info    
                else
                app.pl = app.rays{1}.PathLoss;
                fullpl = 0;
                indpl = 0;
                counter = 0;
                numReflections = 0;
                for i = 1:length(app.rays{1})
                        indpl = app.rays{1}(i).PathLoss;
                        fullpl = fullpl + indpl;
                        numReflections = numReflections + app.rays{1}(i).NumReflections;
                        counter = counter + 1;
                end
                app.NumRaysText.Text = num2str(counter);
                app.AvgPathLossText.Text = strcat(num2str(fullpl / counter), "dB");
                app.NumReflectText.Text = num2str(numReflections);
                app.signalStrengthDefault = sigstrength(app.reciever, app.transmitter, app.rtpm);
                app.defDistance = distance(app.transmitter, app.reciever);
                app.DefaultDistanceText.Text = strcat(num2str(app.defDistance, 6), "m");
                app.ssd = num2str(app.signalStrengthDefault, 5);
                app.DefaultStrengthText.Text =  strcat(app.ssd, "dBm");
                app.strength2 = app.ssd;
                drawnow
                end
                
                %calculates the difference in received signal power
                if all(app.strength1 > 0) && all(app.strength2 > 0)
                    d1 = str2double(app.strength1);
                    d2 = str2double(app.strength2);
                    pd = (-d1) - (-d2);
                    if pd > 0
                        app.PowerDifferenceText.Text = strcat("-", num2str(pd));
                    elseif pd <= 0
                        app.PowerDifferenceText.Text = strcat("+", num2str(pd));
                    end
                end
                
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
        app.amplitude = app.AmplitudeSlider.Value;
            
        end

        % Callback function
        function AntennaHeightEditFieldValueChanged(app, event)
            app.antenna_height = str2double(app.AntennaHeightEditField);
            
        end

        % Value changed function: RecieverSitelatandlongEditField
        function RecieverSitelatandlongEditFieldValueChanged(app, event)
            app.LLArray = strsplit(app.RecieverSitelatandlongEditField.Value, ",");
            app.recLat = str2double(app.LLArray{1,1});
            app.recLong = str2double(app.LLArray{1,2});
            
        end

        % Callback function
        function DisplaySignalStrengthButtonPushed(app, event)
            
        end

        % Value changed function: BuildingMaterialDropDown
        function BuildingMaterialDropDownValueChanged(app, event)
            app.bMaterial = app.BuildingMaterialDropDown.Value;
        end

        % Value changed function: CityDropDown
        function CityDropDownValueChanged(app, event)
            app.city = app.CityDropDown.Value;
            
        end

        % Value changed function: TerrainMaterialDropDown
        function TerrainMaterialDropDownValueChanged(app, event)
            app.tMaterial = app.TerrainMaterialDropDown.Value;
        end

        % Value changed function: TypeofModelDropDown
        function TypeofModelDropDownValueChanged(app, event)
            app.modelType = app.TypeofModelDropDown.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {542, 542};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {265, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GenerateModelButton = uibutton(app.LeftPanel, 'push');
            app.GenerateModelButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateModelButtonPushed, true);
            app.GenerateModelButton.Text = 'Generate Model';
            app.AmplitudeSliderLabel = uilabel(app.LeftPanel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.LeftPanel);
            app.AmplitudeSlider.Limits = [10 100];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.AmplitudeSlider.Value = 10;
            app.BuildingMaterialLabel = uilabel(app.LeftPanel);
            app.BuildingMaterialLabel.Text = {'Building'; 'Material'};
            app.BuildingMaterialDropDown = uidropdown(app.LeftPanel);
            app.BuildingMaterialDropDown.Items = {'perfect-reflector', 'concrete', 'wood', 'metal', 'glass'};
            app.BuildingMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @BuildingMaterialDropDownValueChanged, true);
            app.BuildingMaterialDropDown.Value = 'perfect-reflector';
            app.WavePropagationModelLabel = uilabel(app.LeftPanel);
            app.WavePropagationModelLabel.Text = 'Wave Propagation Model';
            app.TypeofModelDropDownLabel = uilabel(app.LeftPanel);
            app.TypeofModelDropDownLabel.Text = 'Type of Model';
            app.TypeofModelDropDown = uidropdown(app.LeftPanel);
            app.TypeofModelDropDown.Items = {'Path Loss', 'Power Loss'};
            app.TypeofModelDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofModelDropDownValueChanged, true);
            app.TypeofModelDropDown.Value = 'Path Loss';
            app.RecieverSitelatandlongLabel = uilabel(app.LeftPanel);
            app.RecieverSitelatandlongLabel.Text = {'Reciever Site '; '(lat. and long.)'};
            app.RecieverSitelatandlongEditField = uieditfield(app.LeftPanel, 'text');
            app.RecieverSitelatandlongEditField.ValueChangedFcn = createCallbackFcn(app, @RecieverSitelatandlongEditFieldValueChanged, true);
            app.CityDropDownLabel = uilabel(app.LeftPanel);
            app.CityDropDownLabel.Text = 'City';
            app.CityDropDown = uidropdown(app.LeftPanel);
            app.CityDropDown.Items = {'Toronto', 'London'};
            app.CityDropDown.ValueChangedFcn = createCallbackFcn(app, @CityDropDownValueChanged, true);
            app.CityDropDown.Value = 'Toronto';
            app.TerrainMaterialLabel = uilabel(app.LeftPanel);
            app.TerrainMaterialLabel.Text = {'Terrain'; 'Material'};
            app.TerrainMaterialDropDown = uidropdown(app.LeftPanel);
            app.TerrainMaterialDropDown.Items = {'perfect-reflector', 'concrete'};
            app.TerrainMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @TerrainMaterialDropDownValueChanged, true);
            app.TerrainMaterialDropDown.Value = 'concrete';
            app.RightPanel = uipanel(app.GridLayout);
            app.DefaultStrengthText = uilabel(app.RightPanel);
            app.DefaultStrengthText.Text = '';
            app.TransmissionpowerLabel = uilabel(app.RightPanel);
            app.TransmissionpowerLabel.Text = 'Transmission power:';
            app.DirectDistancemLabel = uilabel(app.RightPanel);
            app.DirectDistancemLabel.Text = 'Direct Distance (m):';
            app.DefaultDistanceText = uilabel(app.RightPanel);
            app.DefaultDistanceText.Text = '';
            app.AveragePathLossLabel = uilabel(app.RightPanel);
            app.AveragePathLossLabel.Text = 'Average Path Loss:';
            app.AvgPathLossText = uilabel(app.RightPanel);
            app.AvgPathLossText.Text = '';
            app.NumberofreflectionsLabel = uilabel(app.RightPanel);
            app.NumberofreflectionsLabel.Text = 'Number of reflections:';
            app.NumReflectText = uilabel(app.RightPanel);
            app.NumReflectText.Text = '';
            app.NumRaysLabel = uilabel(app.RightPanel);
            app.NumRaysLabel.Text = 'Num Rays:';
            app.NumRaysText = uilabel(app.RightPanel);
            app.NumRaysText.Text = '';
            app.TransmissionDetailsLabel = uilabel(app.RightPanel);
            app.TransmissionDetailsLabel.Text = 'Transmission Details';
            app.DefaultStrengthText_2 = uilabel(app.RightPanel);
            app.DefaultStrengthText_2.Text = '';
            app.DefaultDistanceText_2 = uilabel(app.RightPanel);
            app.DefaultDistanceText_2.Text = '';
            app.AvgPathLossText_2 = uilabel(app.RightPanel);
            app.AvgPathLossText_2.Text = '';
            app.NumReflectText_2 = uilabel(app.RightPanel);
            app.NumReflectText_2.Text = '';
            app.NumRaysText_2 = uilabel(app.RightPanel);
            app.NumRaysText_2.Text = '';
            app.Model1Label = uilabel(app.RightPanel);
            app.Model1Label.Text = 'Model 1';
            app.Model2Label = uilabel(app.RightPanel);
            app.Model2Label.Text = 'Model 2';
            app.DifferenceinreceivedpowerbetweenmodelsLabel = uilabel(app.RightPanel);
            app.DifferenceinreceivedpowerbetweenmodelsLabel.Text = {'Difference in received power'; 'between models'};
            app.PowerDifferenceText = uilabel(app.RightPanel);
            app.PowerDifferenceText.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rwwp_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end